classdef AUDDIT_DemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BlebMaxFeretDiameterCheckBox  matlab.ui.control.CheckBox
        BlebMinFeretDiameterCheckBox  matlab.ui.control.CheckBox
        BlebExtentCheckBox            matlab.ui.control.CheckBox
        BlebLocationCheckBox          matlab.ui.control.CheckBox
        BlebEccentricityCheckBox      matlab.ui.control.CheckBox
        BlebPerimeterCheckBox         matlab.ui.control.CheckBox
        BlebMinorAxisLengthCheckBox   matlab.ui.control.CheckBox
        BlebMajorAxisLengthCheckBox   matlab.ui.control.CheckBox
        BlebCircularityCheckBox       matlab.ui.control.CheckBox
        BlebIntensityCheckBox         matlab.ui.control.CheckBox
        BlebAreaCheckBox              matlab.ui.control.CheckBox
        BrowseButton_2                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        FinishedButton                matlab.ui.control.Button
        DendriteIntensitiesCheckBox   matlab.ui.control.CheckBox
        DendriteLengthCheckBox        matlab.ui.control.CheckBox
        DendriteRemainingCheckBox     matlab.ui.control.CheckBox
        BlebsperDendriteCheckBox      matlab.ui.control.CheckBox
        PrintResultsButton            matlab.ui.control.Button
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        IncorrectlyCroppedImagesEditField  matlab.ui.control.EditField
        Label_3                       matlab.ui.control.Label
        RunAlgorithmButton            matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        AnalyzeDendritesLabel         matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SaveDirectoryEditFieldLabel   matlab.ui.control.Label
        FileDirectoryEditFieldLabel   matlab.ui.control.Label
        PixelSizeEditField            matlab.ui.control.EditField
        PixelSizeEditFieldLabel       matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
        AUDDITLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        pxlSize = 1.0; 
        saveDirectory = ''; 
        fileDirectory = ''; 
        refPxlSize = 0.38;
        incorrectnums = []; 
        emailAddress = ''; 
        MasterFeatureTable = []; 
        sproutMasterTable = []; 
        blebsNoneTable = []; 
        dendriteLengths = {}; 
        dendriteBreakSeqs = {}; 
        dendriteIntensities = {}; 
        imgSize = {}; 
        croppedImagesOrig = {}; 
        croppedImages = {}; 
        dendriteBreakProp = {}; 
        experiment = ''; 
        folderNames = {}; 
        checkRemaining = false(1); 
        checkDendLength = false(1); 
        checkBlebsPerDend = false(1); 
        checkDendIntensity = false(1); 
        checkFeatsPerLength = false(1); 
        checkBlebArea = false(1); 
        checkBlebMeanIntensity = false(1); 
        checkBlebCircularity = false(1); 
        checkBlebMajorAxisLength = false(1); 
        incorrImgs = {}; 
        blindTable = []; 
        dendriteWidths = []; 
        checkBlebMaxFeret = false(1); 
        checBlebMinFeret = false(1); 
        checkBlebMinor = false(1); 
        checkBlebPerimeter = false(1); 
        checkBlebEccentricity = false(1); 
        checkBlebLocation = false(1); 
        checkBlebExtent = false(1); 
        imageFiles = {}; 
        fldrnames = {}; 
        subFolderNames = {}; 
        subFolders = {}; 
        dendriteMasterTable = []; 

        dendriteBreakPropXcelTable = []; 
        dendriteBreakPropXcel = {};  
        dendriteLengthsXcelTable = []; 
        dendriteLengthsXcel = {} 
        dendriteIntensitiesXcelTable = []; 
        dendriteIntensitiesXcel = {}; 

        dendriteTable = []; 
        dendriteTableDeleted = []; 
       

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PixelSizeEditField
        function PixelSizeEditFieldValueChanged(app, event)
            app.pxlSize = str2num(app.PixelSizeEditField.Value); 
            disp(num2str(app.pxlSize)); 
            
        end

        % Callback function
        function FileDirectoryEditFieldValueChanged(app, event)
            app.fileDirectory = app.FileDirectoryEditField.Value;
            app.fileDirectory = convertCharsToStrings(app.fileDirectory); 
            disp(app.fileDirectory); 
            %addpath(genpath(app.fileDirectory)); 
        end

        % Callback function
        function SaveDirectoryEditFieldValueChanged(app, event)
            app.saveDirectory = app.SaveDirectoryEditField.Value;
            disp(app.saveDirectory); 
        end

        % Button pushed function: RunAlgorithmButton
        function RunAlgorithmButtonPushed(app, event)
            %cd(app.fileDirectory); 
            f = msgbox("Please wait...");
            sFactor = app.refPxlSize/app.pxlSize; 
            %cd(app.fileDirectory) 
            %myDir = dir(app.fileDirectory);
            %myDirIdx = [myDir.isdir;];
            %myDir = myDir(myDirIdx);
            %app.folderNames = {myDir.name};
            %app.folderNames = app.folderNames(~ismember(app.folderNames,{'.','..'}));
                        
            blindIdx = 0; 
            blindIdxfldr = []; 
            blindIdxnum = []; 
        %%
        % FOR LOOP OVER NUMBER OF IMAGES
        for fldr = 1:length(app.subFolderNames)
        % for fldr = 3
            %cd(app.folderNames{fldr}) % change directory to folder of interest
        
            %parent = pwd;
            image_names = dir(fullfile(app.fileDirectory,strcat('**\',char(app.subFolderNames(fldr))),'*.tif'));
            image_names = {image_names.name}; % Sorts files by number (ex: 1,2,3,...10,11)
            image_names = natsortfiles(image_names);
            blebTable = [];
            reDoImg = zeros(size(image_names,2),1);

            dendriteGroupData{fldr} = fldr+zeros(length(image_names)*4,1); %group data for break table 
            dendriteGroupNameData{fldr} =  app.subFolderNames(fldr) + strings(length(image_names)*4,1); 
            dendriteWormNumberData{fldr} = reshape(repmat([1:length(image_names)], 4,1 ), 1, [] ); %worm number data for break table 
            
            dendriteNumberVector = [1,2,3,4]; %template for dendrite numbers 
            dendriteNumberData{fldr} = repmat(dendriteNumberVector,1,length(image_names)); 
        
            for img_num = 1:length(image_names)
                blindIdx = blindIdx + 1; 
                blindIdxfldr = [blindIdxfldr;fldr]; 
                blindIdxnum = [blindIdxnum;img_num]; 


        %     for img_num = 2
            
               close all
               catchImg = 0; 
            file=fullfile(app.fileDirectory,strcat('\',char(app.subFolderNames(fldr))),image_names{img_num});
            im = (imread(file));
            [cropped,imOrig] = findDendrites(im,1,sFactor); %Crop down the image 
             %figure();
             %imshowpair(im,cropped,'montage'); % Show cropped image
             %title(sprintf('Img Num: %d',img_num));
        
            %% THIS SECTION IMPROVES IMAGE QUALITY, THRESHOLDS AND GET ORIGINAL BLEB DATA
            cropped2 = im2uint8(cropped); % Change image to 8 bit (from 16 bit) DO here to not effect imadjust function earlier
            imOrig2 = im2uint8(imOrig);
            [imLocCont, imFeatures, imDend, imOrigBig] = findBlebs(imOrig2,sFactor);
        
            %% Track dendrites using background sub image and increased in size
            
            % Tracks dendrites without machine learning
            [nuer1mask,nuer2mask,nuer3mask,nuer4mask,dendInterp,n1,n2,n3,n4,dendWidth]...
                 = trackDendrites(imLocCont,imFeatures,sFactor); % Returns masks with each tracked dendrite
        
            % Try to catch if it's the wrong side if too much is binarized
            iCheck = imbinarize(imLocCont);
            iFor = length(find(iCheck));
            iTot = size(iCheck,1)*size(iCheck,2);
            iProp = iFor/iTot;
        
            if iProp > .21 % WAS 0.2
                catchImg = 1;
            end
            if size(cropped,2)*app.pxlSize < 8 % old 8
                catchImg = 1;
            end
            if size(cropped,1)*app.pxlSize < 30 % old 15
                catchImg = 1;
            end
                
                % Wrong side of dendrite was detected - run through again with opposite
                % side
                if catchImg == 1
                    reDoImg(img_num) = 1;
                    [cropped,imOrig] = findDendrites(im,0,sFactor); % it was wrong so try again
                    imshowpair(im,cropped,'montage');
                    cropped2 = im2uint8(cropped);
                    imOrig2 = im2uint8(imOrig);
                    [imLocCont, imFeatures, imDend, imOrigBig] = findBlebs(imOrig2,sFactor); % Find features
                    [nuer1mask,nuer2mask,nuer3mask,nuer4mask,dendInterp,n1,n2,n3,n4,dendWidth]...
                        = trackDendrites(imLocCont,imFeatures,sFactor); % Track dendrites
                end
            
            
                %% Show dendrite locations and tracks
                nuermask = nuer1mask | nuer2mask | nuer3mask | nuer4mask;
                nuermask = logical(nuermask);
        %         imshowpair(imbwG2,nuermask)
                
                %% Find breaks using high contrast adjusted images
        %     [dendBreak,breakImg1,breakImg2,breakImg3,breakImg4,b1start,b2start,b3start,b4start,dendIntensity] = ...
        %         breakAnalysis_v2(nuer1mask,nuer2mask,nuer3mask,nuer4mask,img2,imbwG2,{'Example'},1,imOrigBig); %% NO NEED TO SCALE - it just finds where breaks are - scaling done in other functions
            
                [dendBreak,breakImg1,breakImg2,breakImg3,breakImg4,b1start,b2start,b3start,b4start,dendIntensity] = ...
            breakAnalysis(n1,n2,n3,n4,imDend,imOrigBig);  
            
                %% Find lengths of breaks
                [break1start,break1len,~] = breakLengths(dendBreak(1,:)); % NO NEED TO SCALE
                [break2start,break2len,~] = breakLengths(dendBreak(2,:));
                [break3start,break3len,~] = breakLengths(dendBreak(3,:));
                [break4start,break4len,~] = breakLengths(dendBreak(4,:));
                breakLen = {break1len,break2len,break3len,break4len};
           
                % This section gets total dendrite length
                d1length = break1start(end)-b1start;
                d2length = break2start(end)-b2start;
                d3length = break3start(end)-b3start;
                d4length = break4start(end)-b4start;
                dLength = [d1length;d2length;d3length;d4length];
        
                % This section determines break proportion
                totBreak = sum(dendBreak,2);
                totBreakPrp = totBreak./size(imLocCont,1);
        %         totBreakPrp = totBreak./dLength;


                %% Gather bleb data for analysis
                [blebs1data,blebs2data,blebs3data,blebs4data,blebsNoneData] =...
                    blebAnalysis(n1,n2,n3,n4,imFeatures,sFactor,totBreakPrp,dLength,imOrigBig,dendIntensity);
        
                %% Place bleb data into a master table for clustering
               
                %--- Variable names if empty array ---%
                FeatureNames = {'Area'	'Centroid'	'BoundingBox'	'SubarrayIdx'	'MajorAxisLength'...
                    'MinorAxisLength'	'Eccentricity'	'Orientation'	'ConvexHull'	'ConvexImage'...
                    'ConvexArea'	'Circularity'	'Image'	'FilledImage'	'FilledArea'	'EulerNumber'...
                    'Extrema'	'EquivDiameter'	'Solidity'	'Extent'	'PixelIdxList'	'PixelList'	'Perimeter'...
                    'PerimeterOld'	'PixelValues'	'WeightedCentroid'	'MeanIntensity'	'MinIntensity'	'MaxIntensity'...
                    'MaxFeretDiameter'	'MaxFeretAngle'	'MaxFeretCoordinates'	'MinFeretDiameter'	'MinFeretAngle'...
                    'MinFeretCoordinates'	'BlebNumber' 'BreakLength' 'DendriteLength' 'Dendrite' 'DendriteIntensity'...
        	        'ImageNumber'	'Folder' 'ImageSize'};
        
                FeatureNames2 = {'Area'	'Centroid'	'BoundingBox'	'SubarrayIdx'	'MajorAxisLength'...
                    'MinorAxisLength'	'Eccentricity'	'Orientation'	'ConvexHull'	'ConvexImage'...
                    'ConvexArea'	'Circularity'	'Image'	'FilledImage'	'FilledArea'	'EulerNumber'...
                    'Extrema'	'EquivDiameter'	'Solidity'	'Extent'	'PixelIdxList'	'PixelList'	'Perimeter'...
                    'PerimeterOld'	'PixelValues'	'WeightedCentroid'	'MeanIntensity'	'MinIntensity'	'MaxIntensity'...
                    'MaxFeretDiameter'	'MaxFeretAngle'	'MaxFeretCoordinates'	'MinFeretDiameter'	'MinFeretAngle'...
                    'MinFeretCoordinates'	'BlebNumber'	'ImageNumber'	'Folder' 'ImageSize'};


                % Add image number to table
                blebs1data.ImageNumber = img_num.*ones(size(blebs1data,1),1);
                blebs2data.ImageNumber = img_num.*ones(size(blebs2data,1),1);
                blebs3data.ImageNumber = img_num.*ones(size(blebs3data,1),1);
                blebs4data.ImageNumber = img_num.*ones(size(blebs4data,1),1);
                blebsNoneData.ImageNumber = img_num.*ones(size(blebsNoneData,1),1);
                %sproutData.ImageNumber = img_num.*ones(size(sproutData,1),1);
        
                % Add folder to table
                blebs1data.Folder = fldr.*ones(size(blebs1data,1),1);
                blebs2data.Folder = fldr.*ones(size(blebs2data,1),1);
                blebs3data.Folder = fldr.*ones(size(blebs3data,1),1);
                blebs4data.Folder = fldr.*ones(size(blebs4data,1),1);
                blebsNoneData.Folder = fldr.*ones(size(blebsNoneData,1),1);
                %sproutData.Folder = fldr.*ones(size(sproutData,1),1);
        
                blebs1data.ImageSize = size(cropped,1).*ones(size(blebs1data,1),1);
                blebs2data.ImageSize = size(cropped,1).*ones(size(blebs2data,1),1);
                blebs3data.ImageSize = size(cropped,1).*ones(size(blebs3data,1),1);
                blebs4data.ImageSize = size(cropped,1).*ones(size(blebs4data,1),1);
                blebsNoneData.ImageSize = size(cropped,1).*ones(size(blebsNoneData,1),1);
                %sproutData.ImageSize = size(cropped,1).*ones(size(sproutData,1),1);
        
                % Correct is empty
                if isempty(blebs1data)
                    blebs1data = array2table(zeros(0,43));
                    blebs1data.Properties.VariableNames = FeatureNames;
                end
                if isempty(blebs2data)
                    blebs2data = array2table(zeros(0,43));
                    blebs2data.Properties.VariableNames = FeatureNames;
                end
                if isempty(blebs3data)
                    blebs3data = array2table(zeros(0,43));
                    blebs3data.Properties.VariableNames = FeatureNames;
                end
                if isempty(blebs4data)
                    blebs4data = array2table(zeros(0,43));
                    blebs4data.Properties.VariableNames = FeatureNames;
                end
                if isempty(blebsNoneData)
                    blebsNoneData = array2table(zeros(0,39));
                    blebsNoneData.Properties.VariableNames = FeatureNames2;
                end
                %if isempty(sproutData)
                %    sproutData = array2table(zeros(0,39));
                %    sproutData.Properties.VariableNames = FeatureNames2;
                %end
                    
                    % Combine it all
                    app.MasterFeatureTable = [app.MasterFeatureTable;blebs1data;blebs2data;blebs3data;blebs4data];
                    app.blebsNoneTable = [app.blebsNoneTable;blebsNoneData];
            
            
                    %% Put data into variables for looping + later analysis        
                    app.dendriteBreakProp{img_num,fldr} = totBreakPrp';
                    app.dendriteBreakSeqs{img_num,fldr} = breakLen;
                    app.dendriteLengths{img_num,fldr} = dLength*app.pxlSize./4; %DIVIDE BY 4 BECAUSE IMG RESIZE
                    app.dendriteIntensities{img_num,fldr} = dendIntensity;
                    app.dendriteWidths{img_num,fldr} = dendWidth;


                    % Image data
                    app.imgSize{img_num,fldr} = size(cropped,1); 
                    app.croppedImages{img_num,fldr} = imLocCont;
                    app.croppedImagesOrig{img_num,fldr} = imOrigBig;


                %% SAVE PDF IMAGES FOR VIZUAL INSPECTION
                close all
                %cd ..
            
                %{
                for i=1:length(app.subFolderNames)
                    app.dendriteBreakPropXcel{i} = vertcat(app.dendriteBreakProp{:,i}); 
                    app.dendriteLengthsXcel{i} = vertcat(app.dendriteLengths{:,i}); 
                    app.dendriteIntensitiesXcel{i} = vertcat(app.dendriteIntensities{:,i}); 
                end
                for i=1:length(app.subFolderNames)
                    app.dendriteBreakPropXcelTable = vertcat(app.dendriteBreakPropXcelTable,app.dendriteBreakPropXcel{i}); 
                    app.dendriteLengthsXcelTable = vertcat(app.dendriteLengthsXcelTable,app.dendriteLengthsXcel{i}); 
                    app.dendriteIntensitiesXcelTable = vertcat(app.dendriteIntensitiesXcelTable,app.dendriteIntensitiesXcel{i}); 
                end
                %}


                %---- BLEB IMAGES ----%
                figure();
                imshow(imOrigBig)
                imshowpair(imFeatures,imLocCont)
                title(sprintf('Folder: %d Image: %d',fldr,img_num),'FontSize',26)
                f = gcf;
                exportgraphics(f,fullfile(app.saveDirectory,sprintf(strcat(convertCharsToStrings(char(app.subFolderNames(fldr))),'_blebs.pdf'))),'Resolution',100,'BackgroundColor','none','Append',true)
            
             
                %--- BREAK IMAGES ----%
                breakImg = breakImg1|breakImg2|breakImg3|breakImg4;
                figure();
                imshowpair(breakImg,imLocCont)
                title(sprintf('Folder: %d Image: %d',fldr,img_num),'FontSize',26)
                f = gcf;
                exportgraphics(f,fullfile(app.saveDirectory,sprintf(strcat(convertCharsToStrings(char(app.subFolderNames(fldr))),'_breaks.pdf'))),'Resolution',100,'BackgroundColor','none','Append',true)
            % 
            % 
            % %--------- TRACK IMAGES ---------%
                
                figure();
                imshowpair(imLocCont,nuermask)
                title(sprintf('Folder: %d Image: %d',fldr,img_num),'FontSize',26)
                f = gcf;
                exportgraphics(f,fullfile(app.saveDirectory,sprintf(strcat(convertCharsToStrings(char(app.subFolderNames(fldr))),'_tracks.pdf'))),'Resolution',100,'BackgroundColor','none','Append',true) 
                
                %BLIND TRACK
                figure();
                imshowpair(imLocCont,nuermask)
                title(blindIdx,'FontSize',26)
                f = gcf;
                exportgraphics(f,fullfile(app.saveDirectory,'blind_tracks.pdf'),'Resolution',100,'BackgroundColor','none','Append',true) 
                %}
                % Change Back
                %cd(app.folderNames{fldr})
                
            
            end
                
            
            %cd .. % Change back to main folder
        end 

        for i=1:length(app.subFolderNames)
                    breakPropData{i} = horzcat(app.dendriteBreakProp{:,i});
                    dendriteIntensityData{i} = vertcat(app.dendriteIntensities{:,i});
                    dendriteLengthData{i} = vertcat(app.dendriteLengths{:,i});
                    
        end 

        for i=1:length(app.subFolderNames)
            dendriteLengthData{i} = dendriteLengthData{i}.'; 
            dendriteIntensityData{i} = dendriteIntensityData{i}.'; 
            dendriteGroupData{i} = dendriteGroupData{i}.'; 
        end 

        dendriteGroupNameDataTable = vertcat(dendriteGroupNameData{:});
        groupTable = cell2mat(dendriteGroupData); 
        groupTable = groupTable.'; 
        dendriteWormNumberTable = cell2mat(dendriteWormNumberData); 
        dendriteWormNumberTable = dendriteWormNumberTable.'; 
        dendriteNumberTable = cell2mat(dendriteNumberData); 
        dendriteNumberTable = dendriteNumberTable.'; 
        breakPropTable = cell2mat(breakPropData); 
        dendriteIntensityTable = cell2mat(dendriteIntensityData); 
        dendriteLengthTable = cell2mat(dendriteLengthData); 
        
        breakPropTable = breakPropTable.'; 
        dendriteLengthTable = dendriteLengthTable.'; 
        dendriteIntensityTable = dendriteIntensityTable.'; 

        
        app.dendriteTable = table(dendriteGroupNameDataTable,groupTable,dendriteNumberTable,dendriteLengthTable,breakPropTable,dendriteIntensityTable); 
        %dendriteTable.Group = groupTable; 
        %dendriteTable.WormNumber = dendriteWormNumberTable;        error
        %dendriteTable.DendriteNumber = dendriteNumberTable; 
        %dendriteTable.BreakProportions = breakPropTable; 
        %dendriteTable.DendriteIntensity = dendriteIntensityTable; 
        %dendriteTable.Length = dendriteLengthTable; 


        MasterFeatureTable = app.MasterFeatureTable; 
        blebsNoneTable = app.blebsNoneTable; 
        croppedImages = app.croppedImages;
        dendriteBreakProp = app.dendriteBreakProp; 
        dendriteBreakSeqs = app.dendriteBreakSeqs; 
        dendriteLengths = app.dendriteLengths; 
        imgSize = app.imgSize; 
        dendriteIntensities = app.dendriteIntensities; 
        dendriteWidths = app.dendriteWidths; 
        saveLoc = app.saveDirectory; 

        xcelFeatureTable = removevars(MasterFeatureTable,{'SubarrayIdx','ConvexHull','ConvexImage','Image','FilledImage','Extrema','PixelIdxList','PixelList','PixelValues','MaxFeretCoordinates','MinFeretCoordinates'});
        
        writetable(app.dendriteTable,strcat(saveLoc,'\dendriteTable.xlsx')); 


        % Feature results
        save(strcat(saveLoc,'\FeatureTable.mat'),'MasterFeatureTable','blebsNoneTable');
        writetable(xcelFeatureTable,strcat(saveLoc,'\FeatureTable.xlsx')); 
        % Images
        save(strcat(saveLoc, '\CroppedImages.mat'),'croppedImages'); 
        %writetable(croppedImages,strcat(saveLoc,'\CroppedImages.xls')); 
        % Dendrite info
        save(strcat(saveLoc,'\BreakTable.mat'),'dendriteBreakProp','dendriteBreakSeqs','dendriteLengths','imgSize','dendriteIntensities','dendriteWidths');
        %BreakTable = table(dendriteBreakProp,dendriteBreakSeqs,dendriteLengths,imgSize,dendriteIntensities,dendriteWidths); 
        %writetable(BreakTable,strcat(saveLoc,'\BreakTable.xls')); 

        disp('done'); 
            pxlResize = app.pxlSize./4; %divide by four because of the resize 
            app.MasterFeatureTable.BreakLength = (1-app.MasterFeatureTable.BreakLength).*100;
            app.MasterFeatureTable.DendriteLength = app.MasterFeatureTable.DendriteLength.*pxlResize;
            app.MasterFeatureTable.Area = app.MasterFeatureTable.Area.*pxlResize.^2;
            app.MasterFeatureTable.MajorAxisLength = app.MasterFeatureTable.MajorAxisLength.*pxlResize;
            app.MasterFeatureTable.MinorAxisLength = app.MasterFeatureTable.MinorAxisLength.*pxlResize;
            app.MasterFeatureTable.Perimeter = app.MasterFeatureTable.Perimeter.*pxlResize;
            app.MasterFeatureTable.MaxFeretDiameter = app.MasterFeatureTable.MaxFeretDiameter.*pxlResize;
            app.MasterFeatureTable.MinFeretDiameter = app.MasterFeatureTable.MinFeretDiameter.*pxlResize;


        app.blindTable = [blindIdxfldr,blindIdxnum]; 

        f = msgbox("Operation Completed");

        %disp(app.blindTable);  
        end

        % Value changed function: IncorrectlyCroppedImagesEditField
        function IncorrectlyCroppedImagesEditFieldValueChanged(app, event)
            incorrectchars = app.IncorrectlyCroppedImagesEditField.Value;
            app.incorrectnums = str2num(incorrectchars); 
            app.incorrImgs{end+1} = app.incorrectnums; % incorrImgs is the folder number list  
            disp(app.incorrImgs); 
        end

        % Callback function
        function EmailAddressEditFieldValueChanged(app, event)
            app.emailAddress = app.EmailAddressEditField.Value;
            disp(app.emailAddress); 
            
        end

        % Button pushed function: PrintResultsButton
        function PrintResultsButtonPushed(app, event)
            % Find the pixel size to get actual measurements
            pxlSizeSmall = app.pxlSize./4;% Divided by 4 because of the resize
            minWidth = 0;
            minArea = 0; %200 pxls


            if app.checkRemaining 
                dendHealth = cellfun(@(x) (1-x)*100, app.dendriteBreakProp, 'un', 0); 
                tempMetric = dendHealth; 
             
                % Initialization for figure production
                % cMap = {'k','c','m','k','c','m','k','c','m'};
                %cMap = colormap; 
                figure();
                % Organize data
                groupData = [];
                xGroup = [];
                keyPlot = [];
                xkey = [];
                colors = rand(size(tempMetric,2),3);
                for i = 1:size(tempMetric,2)
                    k=i; 
                     
                
                    % Place into metrics
                    dMetric = cell2mat(tempMetric(:,i)); % place into matrix/vector
                    dMetric = reshape(dMetric,[],1);
                    xMetric = ones(length(dMetric),1)*k;
                    
                    % Overall swarm plot
                    swarmchart(xMetric,dMetric,50,colors(i,:),'filled','MarkerFaceAlpha',0.95)
                    hold on
                    
                
                    % Place into ANOVA/boxchart
                    groupData = [groupData;dMetric];
                    xGroup = [xGroup;xMetric];
                
                end
                
                % Overlay box chart
                for i =1:max(xGroup)
                    idx = find(xGroup==i);
                    x = xGroup(idx);
                    y = groupData(idx);
                    boxchart(x,y,'MarkerStyle','none','BoxFaceColor',colors(i,:),'LineWidth',4,'WhiskerLineColor',colors(i,:))
                end
                
                % Adjust visualizations
                ax=gca;
                ax.FontSize = 30;
                title('Dendrite Remaining','FontSize',40);
                xticks([1:max(xGroup)]);
                xticklabels(app.folderNames)
                xlabel('Experimental Group','FontSize',30)
                ylabel('Dendrite Remaining (%) ','FontSize',30)
                ylim([0 100])
                exportgraphics(gcf,fullfile(app.saveDirectory,strcat('dendHealth','.png')));   
            end 


            if app.checkDendIntensity
                tempMetric = app.dendriteIntensities; 
                colors = rand(size(tempMetric,2),3);
                % Initialization for figure production
                % cMap = {'k','c','m','k','c','m','k','c','m'};
                figure();
                % Organize data
                groupData = [];
                xGroup = [];
                keyPlot = [];
                xkey = [];
                
                for i = 1:size(tempMetric,2)
                    k=i; 
                
                
                    % Place into metrics
                    dMetric = cell2mat(tempMetric(:,i)); % place into matrix/vector
                    dMetric = reshape(dMetric,[],1);
                    xMetric = ones(length(dMetric),1)*k;
                    
                    % Overall swarm plot
                    swarmchart(xMetric,dMetric,50,colors(i,:),'filled','MarkerFaceAlpha',0.95)
                    hold on
                    
                
                    % Place into ANOVA/boxchart
                    groupData = [groupData;dMetric];
                    xGroup = [xGroup;xMetric];
                
                end
                
                % Overlay box chart
                for i =1:max(xGroup)
                    idx = find(xGroup==i);
                    x = xGroup(idx);
                    y = groupData(idx);
                    boxchart(x,y,'MarkerStyle','none','BoxFaceColor',colors(i,:),'LineWidth',4,'WhiskerLineColor',colors(i,:))
                end
                
                % Adjust visualizations
                ax=gca;
                ax.FontSize = 30;
                title('Dendrite Intensity','FontSize',40);
                xticks([1:max(xGroup)]);
                xticklabels(app.folderNames)
                xlabel('Experimental Group','FontSize',30)
                ylabel('Dendrite Intensities (a.u.) ','FontSize',30)
                ylim([0 100])
                exportgraphics(gcf,fullfile(app.saveDirectory,strcat('dendIntensity','.png')));
            end 
            

            if app.checkDendLength
                
                tempMetric = app.dendriteLengths; 
                colors = rand(size(tempMetric,2),3);
                % Initialization for figure production
                % cMap = {'k','c','m','k','c','m','k','c','m'};
                figure();
                % Organize data
                groupData = [];
                xGroup = [];
                keyPlot = [];
                xkey = [];
                
                for i = 1:size(tempMetric,2)
                    k=i; 
                
                
                    % Place into metrics
                    dMetric = cell2mat(tempMetric(:,i)); % place into matrix/vector
                    dMetric = reshape(dMetric,[],1);
                    dMetric = dMetric.*app.pxlSize; 
                    xMetric = ones(length(dMetric),1)*k;
                    
                    % Overall swarm plot
                    swarmchart(xMetric,dMetric,50,colors(i,:),'filled','MarkerFaceAlpha',0.95)
                    hold on
                    
                
                    % Place into ANOVA/boxchart
                    groupData = [groupData;dMetric];
                    xGroup = [xGroup;xMetric];
                
                end
                
                % Overlay box chart
                for i =1:max(xGroup)
                    idx = find(xGroup==i);
                    x = xGroup(idx);
                    y = groupData(idx);
                    boxchart(x,y,'MarkerStyle','none','BoxFaceColor',colors(i,:),'LineWidth',4,'WhiskerLineColor',colors(i,:))
                end
                
                % Adjust visualizations
                ax=gca;
                ax.FontSize = 30;
                title('Dendrite Length','FontSize',40);
                xticks([1:max(xGroup)]);
                xticklabels(app.folderNames)
                xlabel('Experimental Group','FontSize',30)
                ylabel('Dendrite Length (um)','FontSize',30)
                %ylim([0 1000])
                exportgraphics(gcf,fullfile(app.saveDirectory,strcat('dendLength','.png')));   
            end 

            
            %% Metric adjustment
            % Adjust metrics (if necessary)
            

            % Put into new table to permit filtering and/or clustering
            clusterTable = [];
            clusterTable.Area = app.MasterFeatureTable.Area;
            clusterTable.MajorAxisLength = app.MasterFeatureTable.MajorAxisLength;
            clusterTable.MinorAxisLength = app.MasterFeatureTable.MinorAxisLength;
            clusterTable.Circularity = app.MasterFeatureTable.Circularity;
            clusterTable.Eccentricity = app.MasterFeatureTable.Eccentricity;
            clusterTable.Extent = app.MasterFeatureTable.Extent;
            clusterTable.Orientation = app.MasterFeatureTable.Orientation;
            clusterTable.ConvexArea = app.MasterFeatureTable.ConvexArea;
            clusterTable.Solidity = app.MasterFeatureTable.Solidity;
            clusterTable.Perimeter = app.MasterFeatureTable.Perimeter;
            clusterTable.MeanIntensity = app.MasterFeatureTable.MeanIntensity;
            clusterTable.DendriteIntensity = app.MasterFeatureTable.DendriteIntensity;
            clusterTable.BreakLength = app.MasterFeatureTable.BreakLength;
            clusterTable.MaxFeretDiameter = app.MasterFeatureTable.MaxFeretDiameter;
            clusterTable.MinFeretDiameter = app.MasterFeatureTable.MinFeretDiameter;
            
            % Add in location of feature into table
            clusterTable.BlebLocation = app.MasterFeatureTable.Centroid(:,1)./(app.MasterFeatureTable.DendriteLength./app.pxlSize);
            clusterTable.BlebLocation(clusterTable.BlebLocation>1)=1; % Deal with short dendrites for now
            app.MasterFeatureTable.BlebLocation = app.MasterFeatureTable.Centroid(:,1)./(app.MasterFeatureTable.DendriteLength./app.pxlSize);
            app.MasterFeatureTable.BlebLocation(app.MasterFeatureTable.BlebLocation>1)=1;


            % Make everything correct data type for clustering
            clusterTable = struct2table(clusterTable);
            cluster = table2cell(clusterTable);
            clusterMatrix = cellfun(@double, cluster, 'uni', false);
            clusterMatrix = cell2mat(clusterMatrix);
            
            % Filter table to remove 'small' features
            filterCluster = clusterTable;
            filterCluster(filterCluster.MinFeretDiameter < minWidth,:) = [];
            filterCluster(filterCluster.Area < minArea,:) = [];
            filtMasterTable = app.MasterFeatureTable;
            filtMasterTable(filtMasterTable.MinFeretDiameter < minWidth,:) = [];
            filtMasterTable(filtMasterTable.Area < minArea,:) = [];
            filterClusterCell = table2cell(filterCluster);
            filterClusterMatrix = cellfun(@double, filterClusterCell, 'uni', false);
            filterClusterMatrix = cell2mat(filterClusterMatrix);
            
            %% VISUALIZE FEATURE ONLY DATA (Area, Location, etc)
       
            close all
            % Initialize
            
            
            clustTable = filtMasterTable;
            
            if app.checkBlebCircularity
                metric = 'Circularity'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Circularity';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebMajorAxisLength
                metric = 'MajorAxisLength'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Major Axis Length';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebMinor
                metric = 'MinorAxisLength'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Minor Axis Length';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebMeanIntensity
                metric = 'MeanIntensity'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Mean Intensities';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checBlebMinFeret
                metric = 'MinFeretDiameter'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Min Feret Diameter';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebMaxFeret
                metric = 'MaxFeretDiameter'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Max Feret Diameter';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebArea
                metric = 'Area'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Area';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebPerimeter
                metric = 'Perimeter'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Perimeter';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebEccentricity
                metric = 'Eccentricity'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Eccentricity';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebExtent
                metric = 'Extent'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Extent';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 

            if app.checkBlebLocation
                metric = 'BlebLocation'; %%% METRIC SELECTION %%%
                plotTitle = 'Feature Location';
                figureMaking(metric,plotTitle,app.saveDirectory,clustTable,length(app.subFolderNames),app.subFolderNames); 
            end 
    

            %% %% THE FOLLOWING SECTION GETS MORE DIFFICULT METRICS
            if app.checkFeatsPerLength


                % Blebs per dendrite length
                close all
                
                % Define experiments
                groupLabel = app.folderNames; 
                
                
                % Initialize
                ftPerDend = [];
                ftPerDendLen = [];
                ftSpread = [];
                tempMetric = [];
                tempXmetric = [];
                ftCount = [];
                ftCountLen = [];
                groupDataPlot = [];
                xGroupPlot = [];
                
                clustTable = filtMasterTable;
                zz = 0; % counter for storing feature counts
                dendIdx = 0; % counter for comparing dendrite statistics
                xCount = [1:3];
                
                groupDataMetric = [];
                xGroupMetric = [];
                
                % For loop of experiments 
                for i = 1:length(app.folderNames) % WHICH EXPERIMENTS TO RUN
                
                    groupData = [];
                    xGroup = [];
                    groupDataLen = [];
                    xGroupLen = [];
                    groupDataSpread = [];
                    xGroupSpread = [];
                    groupDataW = [];
                    xGroupW = [];
                %     subplot(1,3,i)
                    k=0;
                    for j = 1 % WHICH GROUPS TO RUN
                        k=k+1;
                        zz=zz+1;
                
                        % Initialize
                        ftPerDend = [];
                        ftPerDendLen = [];
                        ftSpread = [];
                        tempMetric = [];
                        tempXmetric = [];
                        dMet = [];
                        xMet = [];
                        tempIdx = [i,i+3,i+6,i+9];
                
                        maxImgIdx= find(clustTable.Folder==tempIdx(j));
                        maxImgTable = clustTable(maxImgIdx,:);
                        maxImg = max(maxImgTable.ImageNumber);
                        % Third for loop for image
                        for img = 1:maxImg
                            for dend = 1:4
                                dendIdx = dendIdx+1;
                                ftSpread = [];
                                tempClustIdx = find(clustTable.Dendrite == dend & filtMasterTable.Folder == tempIdx(j) & filtMasterTable.ImageNumber == img);
                                expData = clustTable(tempClustIdx,:);
                                expCount = size(expData,1);
                
                                dendMetric = expData.DendriteLength*app.pxlSize; % This is where you choose what you want to look at
                                
                                % Feature or bleb dependent
                                if expCount > 0
                                    dendLength = expData.DendriteLength(1)*app.pxlSize;
                                    dendBreak = (expData.BreakLength(1));
                                    totDendLength = dendLength.*dendBreak; % blebs per dendrite length actually there
                
                                    % Get spread of features
                                    if expCount > 3
                                        ftSpread = std(expData.BlebLocation)*app.pxlSize;
                                    end
                
                                else
                                    dendLength = 1*app.pxlSize;
                                    totDendLength = 1*app.pxlSize;
                                    dendBreak = 1;
                                end
                
                                ftPerDend(img,dend) = expCount;
                                ftPerDendLen(img,dend) = expCount./totDendLength;
                                weightFeature = expCount./dendBreak;
                
                                % Gather for ANOVA - features per dendrite
                                groupData = [groupData;expCount];
                                xGroup = [xGroup;ones(length(expCount),1)*k]; 
                
                                % Gather for ANOVA - features per dendrite length
                                groupDataLen = [groupDataLen;ftPerDendLen(img,dend)];
                                xGroupLen = [xGroupLen;ones(length(expCount),1)*k];
                
                                % Gather for ANOVA - features per dendrite length
                                groupDataW = [groupDataW;weightFeature];
                                xGroupW = [xGroupW;ones(length(weightFeature),1)*k]; 
                
                                % Gather for ANOVA - features spread
                                groupDataSpread = [groupDataSpread;ftSpread];
                                xGroupSpread = [xGroupSpread;ones(length(ftSpread),1)*k];
                
                                % Gather for ANOVA - dendrite metric
                                groupDataMetric = [groupDataMetric;weightFeature];
                                xGroupMetric = [xGroupMetric;ones(length(weightFeature),1)*xCount(tempIdx(j))]; 
                                
                                % Organize data for plotting
                                dMet = [dMet;ftPerDendLen(img,dend)];
                                xMet = [xMet;ones(length(ftPerDendLen(img,dend)),1).*xCount(tempIdx(j))];
                %                 
                            end
                            % Place in cell array for super violin plot
                            cellIdx = find(tempXmetric == j);
                            cellData{i,j} = tempMetric(cellIdx);
                        end
                    % Now plot
                    swarmchart(xMet,dMet,50,colors(j,:),'filled','MarkerFaceAlpha',0.95)
                    hold on
                
                    % Place into ANOVA/boxchart
                    groupDataPlot = [groupDataPlot;dMet];
                    xGroupPlot = [xGroupPlot;xMet];
                
                    % Store data for binning and comparisons
                    ftCount{zz} = ftPerDend;
                    ftCountLen{zz} = ftPerDendLen;
                
                    end
                
                end
                
                
                % Overlay box chart
                 
                for i =1:max(xGroupPlot)
                        idx = find(xGroupPlot==i);
                        x = xGroupPlot(idx);
                        y = groupDataPlot(idx);
                        boxchart(x,y,'MarkerStyle','none','BoxFaceColor',colors(j,:),'LineWidth',4,'WhiskerLineColor',colors(j,:))
                end
                
                
                % Make pretty    
                title('Feature Count per Dendrite Length','fontsize',36)
                ax = gca;
                ax.FontSize = 18;
                %xticks([1:3])
                xticklabels(groupLabel)
                ylabel('Feature Count (#/µm)','FontSize',20)
                xlabel('Experiment Group','FontSize',20)
                %ylim([0 .5])
                
                % Adjust size
                set(gcf,'Position',[2 71 1105 906]);


                %{
                % Blebs per dendrite length
                close all
            
                % Initialize
                groupData = [];
                xGroup = [];
                groupDataLen = [];
                xGroupLen = [];
                groupDataSpread = [];
                xGroupSpread = [];
                groupDataMetric = [];
                xGroupMetric = [];
                groupDataW = [];
                xGroupW = [];
                keyPlot = [];
                xkey = [];
                fldr = 0;
                metric = 'BlebLocation';
                clustTable = filtMasterTable;
                figure();
                
                % For loop of experiments 
                for i = 1:length(app.folderNames) % WHICH EXPERIMENTS TO RUN
                    k=0;
                %     cMap = {'k','c','m'};
                    cMap = {'k',[.5 .5 .5],[.25 .25 .25]};
                    for j = 1:1 % WHICH GROUPS TO RUN
                        
                        % Initialize
                        ftPerDend = [];
                        ftPerDendLen = [];
                        ftSpread = [];
                        tempMetric = [];
                        tempXmetric = [];
                        tempIdx = [i,i+3,i+6];
                        maxImgIdx= find(clustTable.Folder==tempIdx(j));
                        maxImgTable = clustTable(maxImgIdx,:);
                        maxImg = max(maxImgTable.ImageNumber);
                        groupPlot = [];
                        xPlot = [];
                        fldr = fldr+1;
                
                        % Third for loop for image
                        for img = 1:maxImg
                %         for img = 3
                            for dend = 1:4
                                ftSpread = [];
                                tempClustIdx = find(clustTable.Dendrite == dend & filtMasterTable.Folder == tempIdx(j) & filtMasterTable.ImageNumber == img);
                                expData = clustTable(tempClustIdx,:);
                                expCount = size(expData,1);
                
                                dendMetric = expData.(metric); % This is where you choose what you want to look at
                                
                                % Feature or bleb dependent
                                if expCount > 0
                                    dendLength = expData.DendriteLength(1);
                                    dendBreak = (expData.BreakLength(1))./100;
                                    totDendLength = dendLength.*dendBreak; % blebs per dendrite length actually there
                
                                    % Get spread of features
                                    if expCount > 4
                                        ftSpread = std(expData.BlebLocation)*app.pxlSize;
                                    end
                
                                else
                                    dendLength = 1*app.pxlSize;
                                    totDendLength = 1;
                                    dendBreak = 1;
                                end
                
                                ftPerDend(img,dend) = expCount;
                                ftPerDendLen(img,dend) = expCount./totDendLength;
                                weightFeature = expCount./dendBreak;
                
                                % Gather for ANOVA - features per dendrite
                                groupData = [groupData;expCount];
                                xGroup = [xGroup;ones(length(expCount),1)*k]; 
                
                                % Gather for ANOVA - features per dendrite length
                                groupDataLen = [groupDataLen;ftPerDendLen(img,dend)];
                                xGroupLen = [xGroupLen;ones(length(expCount),1)*k];
                
                                % Gather for ANOVA - features per dendrite length
                                groupDataW = [groupDataW;weightFeature];
                                xGroupW = [xGroupW;ones(length(weightFeature),1)*k]; 
                
                                % Gather for ANOVA - features spread
                                groupDataSpread = [groupDataSpread;ftSpread];
                                xGroupSpread = [xGroupSpread;ones(length(ftSpread),1)*k];
                
                                % Gather for ANOVA - dendrite metric
                                groupDataMetric = [groupDataMetric;dendMetric];
                                xGroupMetric = [xGroupMetric;ones(length(dendMetric),1)*k]; 
                
                                % Gather data for swarm chart
                                groupPlot = [groupPlot;ftPerDendLen(img,dend)];
                                xPlot = [xPlot;ones(length(ftPerDendLen(img,dend)),1)*k];


                            end
                        end
                
                        % Plotting data - swarm chart
                        swarmchart(xPlot,groupPlot,50,cMap{i},'filled','MarkerFaceAlpha',0.95)
                        hold on
                
                    end
                end
                
                % Overlay box plot
                cMap = {[0 .25 0],[.5 .5 0],[0.25 0 0]};
                for i =1:max(xGroupLen)
                    idx = find(xGroupLen==i);
                    x = xGroupLen(idx);
                    y = groupDataLen(idx);
                    boxchart(x,y,'MarkerStyle','none','BoxFaceColor',cMap{i},'LineWidth',4,'WhiskerLineColor',cMap{i})
                end


                ax = gca;
                % Make pretty
                title('Feature per Dendrite Length','fontsize',40)
                ax = gca;
                ax.FontSize = 30;
                xticks(1:max(xGroupMetric))
                xticklabels(app.folderNames)
                ylabel('Features per Dendrite Length','FontSize',30)
                xlabel('Experiment Group','FontSize',30)
                ylim([0 .3])
                % Adjust size
                set(gcf,'Position',[1369 71 1105 906]);
                %}
            end 
            
        end

        % Callback function
        function ExperimentNameEditFieldValueChanged(app, event)
            app.experiment = app.ExperimentNameEditField.Value;
            disp(app.experiment)
        end

        % Value changed function: DendriteRemainingCheckBox
        function DendriteRemainingCheckBoxValueChanged(app, event)
            app.checkRemaining = app.DendriteRemainingCheckBox.Value;
            
        end

        % Value changed function: BlebsperDendriteCheckBox
        function BlebsperDendriteCheckBoxValueChanged(app, event)
            app.checkFeatsPerLength = app.BlebsperDendriteCheckBox.Value;
            
        end

        % Value changed function: DendriteLengthCheckBox
        function DendriteLengthCheckBoxValueChanged(app, event)
            app.checkDendLength = app.DendriteLengthCheckBox.Value;
            
        end

        % Value changed function: DendriteIntensitiesCheckBox
        function DendriteIntensitiesCheckBoxValueChanged(app, event)
            app.checkDendIntensity = app.DendriteIntensitiesCheckBox.Value;
            
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            for i = 1:size(app.incorrImgs,2)

                
                temp_fldr = app.blindTable(app.incorrImgs{i},1); 
                temp_num = app.blindTable(app.incorrImgs{i},2); 
                %{
                temp_mat = app.incorrImgs{i};  
                temp_num = temp_mat(1,1); 
                temp_fldr = temp_mat(1,2); 
                %}
                
                %delete dendrite data 
                app.dendriteBreakProp{temp_num,temp_fldr} = [];
                app.dendriteBreakSeqs{temp_num,temp_fldr} = [];
                app.dendriteLengths{temp_num,temp_fldr} = [];
                app.dendriteIntensities{temp_num,temp_fldr} = [];
                app.croppedImages{temp_num,temp_fldr} = [];
                app.croppedImagesOrig{temp_num,temp_fldr} = [];

                tempFeats = find(app.MasterFeatureTable.Folder==temp_fldr & app.MasterFeatureTable.ImageNumber==temp_num);
                app.MasterFeatureTable(app.MasterFeatureTable{:,41}==temp_num & app.MasterFeatureTable{:,42} == temp_fldr,:) = []; %delete feature data 


            end 

            xcelFeatureTabelDeleted = app.MasterFeatureTable; 
            xcelFeatureTabelDeleted = removevars(xcelFeatureTabelDeleted,{'SubarrayIdx','ConvexHull','ConvexImage','Image','FilledImage','Extrema','PixelIdxList','PixelList','PixelValues','MaxFeretCoordinates','MinFeretCoordinates'});
            saveLoc = app.saveDirectory; 
            writetable(xcelFeatureTabelDeleted,strcat(saveLoc,'\FeatureTable.xlsx')); 
            
            app.dendriteTableDeleted = app.dendriteTable; 
            dendIndices = cellfun(@(x) (x*4)-3,app.incorrImgs,'un',0); %Multiply by 4 because 4 dendrites 
            dendIndices2 = cellfun(@(x) x+1,dendIndices,'un',0);
            dendIndices3 = cellfun(@(x) x+2,dendIndices,'un',0);
            dendIndices4 = cellfun(@(x) x+3,dendIndices,'un',0);
            app.dendriteTableDeleted([dendIndices{:} dendIndices2{:} dendIndices3{:} dendIndices4{:}], :) = [];
            writetable(app.dendriteTableDeleted,strcat(saveLoc,'\dendriteTableAfterDeletions.xlsx')); 
            

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            dname = uigetdir(); 
            app.fileDirectory = dname;
            app.fileDirectory = convertCharsToStrings(app.fileDirectory); 
            app.imageFiles = dir(fullfile(dname,'**','*.tif'));
            app.fldrnames = dir(fullfile(dname));
            
            dirFlags = [app.fldrnames.isdir];
            % Extract only those that are directories.
            app.subFolders = app.fldrnames(dirFlags); % A structure with extra info.
            % Get only the folder names into a cell array.
            app.subFolderNames = {app.subFolders(3:end).name}; % Start at 3 to skip . and ..


            disp(app.fileDirectory); 
            %addpath(genpath(app.fileDirectory)); 
            
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            dname2 = uigetdir(); 
            app.saveDirectory = dname2; 
            app.saveDirectory = convertCharsToStrings(app.saveDirectory); 
            disp(app.saveDirectory); 
        end

        % Value changed function: BlebAreaCheckBox
        function BlebAreaCheckBoxValueChanged(app, event)
            app.checkBlebArea = app.BlebAreaCheckBox.Value;
            
            
        end

        % Value changed function: BlebIntensityCheckBox
        function BlebIntensityCheckBoxValueChanged(app, event)
            app.checkBlebMeanIntensity = app.BlebIntensityCheckBox.Value;
            
        end

        % Value changed function: BlebCircularityCheckBox
        function BlebCircularityCheckBoxValueChanged(app, event)
            app.checkBlebCircularity = app.BlebCircularityCheckBox.Value;
            
        end

        % Value changed function: BlebMajorAxisLengthCheckBox
        function BlebMajorAxisLengthCheckBoxValueChanged(app, event)
            app.checkBlebMajorAxisLength = app.BlebMajorAxisLengthCheckBox.Value;
            
        end

        % Value changed function: BlebMaxFeretDiameterCheckBox
        function BlebMaxFeretDiameterCheckBoxValueChanged(app, event)
            app.checkBlebMaxFeret = app.BlebMaxFeretDiameterCheckBox.Value;
            
        end

        % Value changed function: BlebMinFeretDiameterCheckBox
        function BlebMinFeretDiameterCheckBoxValueChanged(app, event)
            app.checBlebMinFeret = app.BlebMinFeretDiameterCheckBox.Value;
            
        end

        % Value changed function: BlebMinorAxisLengthCheckBox
        function BlebMinorAxisLengthCheckBoxValueChanged(app, event)
            app.checkBlebMinor = app.BlebMinorAxisLengthCheckBox.Value;
            
        end

        % Value changed function: BlebPerimeterCheckBox
        function BlebPerimeterCheckBoxValueChanged(app, event)
            app.checkBlebPerimeter = app.BlebPerimeterCheckBox.Value;
            
        end

        % Value changed function: BlebEccentricityCheckBox
        function BlebEccentricityCheckBoxValueChanged(app, event)
            app.checkBlebEccentricity = app.BlebEccentricityCheckBox.Value;
            
        end

        % Value changed function: BlebLocationCheckBox
        function BlebLocationCheckBoxValueChanged(app, event)
            app.checkBlebLocation = app.BlebLocationCheckBox.Value;
            
        end

        % Value changed function: BlebExtentCheckBox
        function BlebExtentCheckBoxValueChanged(app, event)
            app.checkBlebExtent = app.BlebExtentCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AUDDITLabel = uilabel(app.UIFigure);
            app.AUDDITLabel.Text = 'AUDDIT';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            app.PixelSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.UIFigure, 'text');
            app.PixelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeEditFieldValueChanged, true);
            app.FileDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.FileDirectoryEditFieldLabel.Text = 'File Directory:';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.AnalyzeDendritesLabel = uilabel(app.UIFigure);
            app.AnalyzeDendritesLabel.Text = 'Analyze Dendrites';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.RunAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.RunAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlgorithmButtonPushed, true);
            app.RunAlgorithmButton.Text = 'Run Algorithm';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.IncorrectlyCroppedImagesEditField = uieditfield(app.UIFigure, 'text');
            app.IncorrectlyCroppedImagesEditField.ValueChangedFcn = createCallbackFcn(app, @IncorrectlyCroppedImagesEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Type Index number of incorrectly analyzed image followed by enter key, press finished when done. ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '4.';
            app.PrintResultsButton = uibutton(app.UIFigure, 'push');
            app.PrintResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PrintResultsButtonPushed, true);
            app.PrintResultsButton.Text = 'Print Results';
            app.BlebsperDendriteCheckBox = uicheckbox(app.UIFigure);
            app.BlebsperDendriteCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebsperDendriteCheckBoxValueChanged, true);
            app.BlebsperDendriteCheckBox.Text = 'Blebs per Dendrite';
            app.DendriteRemainingCheckBox = uicheckbox(app.UIFigure);
            app.DendriteRemainingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DendriteRemainingCheckBoxValueChanged, true);
            app.DendriteRemainingCheckBox.Text = 'Dendrite Remaining';
            app.DendriteLengthCheckBox = uicheckbox(app.UIFigure);
            app.DendriteLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @DendriteLengthCheckBoxValueChanged, true);
            app.DendriteLengthCheckBox.Text = 'Dendrite Length';
            app.DendriteIntensitiesCheckBox = uicheckbox(app.UIFigure);
            app.DendriteIntensitiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DendriteIntensitiesCheckBoxValueChanged, true);
            app.DendriteIntensitiesCheckBox.Text = 'Dendrite Intensities';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.BlebAreaCheckBox = uicheckbox(app.UIFigure);
            app.BlebAreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebAreaCheckBoxValueChanged, true);
            app.BlebAreaCheckBox.Text = 'Bleb Area';
            app.BlebIntensityCheckBox = uicheckbox(app.UIFigure);
            app.BlebIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebIntensityCheckBoxValueChanged, true);
            app.BlebIntensityCheckBox.Text = 'Bleb Intensity';
            app.BlebCircularityCheckBox = uicheckbox(app.UIFigure);
            app.BlebCircularityCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebCircularityCheckBoxValueChanged, true);
            app.BlebCircularityCheckBox.Text = 'Bleb Circularity';
            app.BlebMajorAxisLengthCheckBox = uicheckbox(app.UIFigure);
            app.BlebMajorAxisLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebMajorAxisLengthCheckBoxValueChanged, true);
            app.BlebMajorAxisLengthCheckBox.Text = 'Bleb Major Axis Length';
            app.BlebMinorAxisLengthCheckBox = uicheckbox(app.UIFigure);
            app.BlebMinorAxisLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebMinorAxisLengthCheckBoxValueChanged, true);
            app.BlebMinorAxisLengthCheckBox.Text = 'Bleb Minor Axis Length';
            app.BlebPerimeterCheckBox = uicheckbox(app.UIFigure);
            app.BlebPerimeterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebPerimeterCheckBoxValueChanged, true);
            app.BlebPerimeterCheckBox.Text = 'Bleb Perimeter';
            app.BlebEccentricityCheckBox = uicheckbox(app.UIFigure);
            app.BlebEccentricityCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebEccentricityCheckBoxValueChanged, true);
            app.BlebEccentricityCheckBox.Text = 'Bleb Eccentricity';
            app.BlebLocationCheckBox = uicheckbox(app.UIFigure);
            app.BlebLocationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebLocationCheckBoxValueChanged, true);
            app.BlebLocationCheckBox.Text = 'Bleb Location';
            app.BlebExtentCheckBox = uicheckbox(app.UIFigure);
            app.BlebExtentCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebExtentCheckBoxValueChanged, true);
            app.BlebExtentCheckBox.Text = 'Bleb Extent ';
            app.BlebMinFeretDiameterCheckBox = uicheckbox(app.UIFigure);
            app.BlebMinFeretDiameterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebMinFeretDiameterCheckBoxValueChanged, true);
            app.BlebMinFeretDiameterCheckBox.Text = 'Bleb Min Feret Diameter';
            app.BlebMaxFeretDiameterCheckBox = uicheckbox(app.UIFigure);
            app.BlebMaxFeretDiameterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlebMaxFeretDiameterCheckBoxValueChanged, true);
            app.BlebMaxFeretDiameterCheckBox.Text = 'Bleb Max Feret Diameter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUDDIT_DemoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end